classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        NolinealesTab              matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        Y1EditFieldLabel           matlab.ui.control.Label
        Y1EditField                matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        X2EditField                matlab.ui.control.NumericEditField
        Y2EditFieldLabel           matlab.ui.control.Label
        Y2EditField                matlab.ui.control.NumericEditField
        PuntoLabel                 matlab.ui.control.Label
        DominioLabel               matlab.ui.control.Label
        EscojaelalgoritmoautilizarButtonGroup  matlab.ui.container.ButtonGroup
        CauchyGradienteDescendenteButton  matlab.ui.control.RadioButton
        NewtonButton               matlab.ui.control.RadioButton
        QuasinewtonDFPButton       matlab.ui.control.RadioButton
        QuasinewtonBFGSButton      matlab.ui.control.RadioButton
        CalcularButton             matlab.ui.control.Button
        EscojaelmtododebusquedalinealButtonGroup  matlab.ui.container.ButtonGroup
        BiseccinButton             matlab.ui.control.RadioButton
        WolfeButton                matlab.ui.control.RadioButton
        DespejeButton              matlab.ui.control.RadioButton
        GridLayout4                matlab.ui.container.GridLayout
        FuncinEditFieldLabel       matlab.ui.control.Label
        FuncinEditField            matlab.ui.control.EditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        LinealesTab                matlab.ui.container.Tab
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        X1EditField_2Label         matlab.ui.control.Label
        X1EditField_2              matlab.ui.control.NumericEditField
        X2EditField_2Label         matlab.ui.control.Label
        X2EditField_2              matlab.ui.control.NumericEditField
        XEditField_2Label          matlab.ui.control.Label
        XEditField_2               matlab.ui.control.NumericEditField
        DominioLabel_2             matlab.ui.control.Label
        PuntoinicialLabel          matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        FuncinEditField_2Label     matlab.ui.control.Label
        FuncinEditField_2          matlab.ui.control.EditField
        EscojaelmtododebusquedalinealButtonGroup_2  matlab.ui.container.ButtonGroup
        BiseccinButton_2           matlab.ui.control.RadioButton
        WolfeButton_2              matlab.ui.control.RadioButton
        CalcularButton_2           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            fx = app.FuncinEditField.Value;
            initial = [app.XEditField.Value;app.YEditField.Value];
            maxIter = app.IteracionesEditField.Value;
            busquedaLineal = app.EscojaelmtododebusquedalinealButtonGroup.SelectedObject.Text;
            method = app.EscojaelalgoritmoautilizarButtonGroup.SelectedObject.Text;
            dom1 = [app.X1EditField.Value , app.X2EditField.Value];
            dom2 = [app.Y1EditField.Value, app.Y2EditField.Value];
            ControllerNonLinear(fx, initial, maxIter, busquedaLineal, method,dom1,dom2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NolinealesTab = uitab(app.TabGroup);
            app.NolinealesTab.Title = 'No lineales';
            app.GridLayout2 = uigridlayout(app.NolinealesTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.XEditFieldLabel = uilabel(app.GridLayout3);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YEditFieldLabel = uilabel(app.GridLayout3);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout3, 'numeric');
            app.X1EditFieldLabel = uilabel(app.GridLayout3);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.GridLayout3);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.X2EditFieldLabel = uilabel(app.GridLayout3);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.GridLayout3);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.PuntoLabel = uilabel(app.GridLayout3);
            app.PuntoLabel.Text = 'Punto';
            app.DominioLabel = uilabel(app.GridLayout3);
            app.DominioLabel.Text = 'Dominio';
            app.EscojaelalgoritmoautilizarButtonGroup = uibuttongroup(app.GridLayout2);
            app.EscojaelalgoritmoautilizarButtonGroup.Title = 'Escoja el algoritmo a utilizar';
            app.CauchyGradienteDescendenteButton = uiradiobutton(app.EscojaelalgoritmoautilizarButtonGroup);
            app.CauchyGradienteDescendenteButton.Text = 'Cauchy - Gradiente Descendente';
            app.CauchyGradienteDescendenteButton.Value = true;
            app.NewtonButton = uiradiobutton(app.EscojaelalgoritmoautilizarButtonGroup);
            app.NewtonButton.Text = 'Newton';
            app.QuasinewtonDFPButton = uiradiobutton(app.EscojaelalgoritmoautilizarButtonGroup);
            app.QuasinewtonDFPButton.Text = 'Quasi newton - DFP';
            app.QuasinewtonBFGSButton = uiradiobutton(app.EscojaelalgoritmoautilizarButtonGroup);
            app.QuasinewtonBFGSButton.Text = 'Quasi newton - BFGS';
            app.CalcularButton = uibutton(app.GridLayout2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.EscojaelmtododebusquedalinealButtonGroup = uibuttongroup(app.GridLayout2);
            app.EscojaelmtododebusquedalinealButtonGroup.Title = 'Escoja el método de busqueda lineal';
            app.BiseccinButton = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup);
            app.BiseccinButton.Text = 'Bisección';
            app.BiseccinButton.Value = true;
            app.WolfeButton = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup);
            app.WolfeButton.Text = 'Wolfe';
            app.DespejeButton = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup);
            app.DespejeButton.Text = 'Despeje';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.FuncinEditFieldLabel = uilabel(app.GridLayout4);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.GridLayout4, 'text');
            app.IteracionesEditFieldLabel = uilabel(app.GridLayout4);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LinealesTab = uitab(app.TabGroup);
            app.LinealesTab.Title = 'Lineales';
            app.GridLayout5 = uigridlayout(app.LinealesTab);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.X1EditField_2Label = uilabel(app.GridLayout6);
            app.X1EditField_2Label.Text = 'X1';
            app.X1EditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.X2EditField_2Label = uilabel(app.GridLayout6);
            app.X2EditField_2Label.Text = 'X2';
            app.X2EditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.XEditField_2Label = uilabel(app.GridLayout6);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.DominioLabel_2 = uilabel(app.GridLayout6);
            app.DominioLabel_2.Text = 'Dominio';
            app.PuntoinicialLabel = uilabel(app.GridLayout6);
            app.PuntoinicialLabel.Text = 'Punto inicial';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.FuncinEditField_2Label = uilabel(app.GridLayout7);
            app.FuncinEditField_2Label.Text = 'Función';
            app.FuncinEditField_2 = uieditfield(app.GridLayout7, 'text');
            app.EscojaelmtododebusquedalinealButtonGroup_2 = uibuttongroup(app.GridLayout5);
            app.EscojaelmtododebusquedalinealButtonGroup_2.Title = 'Escoja el método de busqueda lineal';
            app.BiseccinButton_2 = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup_2);
            app.BiseccinButton_2.Text = 'Bisección';
            app.BiseccinButton_2.Value = true;
            app.WolfeButton_2 = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup_2);
            app.WolfeButton_2.Text = 'Wolfe';
            app.CalcularButton_2 = uibutton(app.GridLayout5, 'push');
            app.CalcularButton_2.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end